classdef rzut_ukosny < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ZapisgryTextArea          matlab.ui.control.TextArea
        ZapisgryButton            matlab.ui.control.Button
        DwikSwitch                matlab.ui.control.Switch
        DwikSwitchLabel           matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        CheckBox                  matlab.ui.control.CheckBox
        ResetButton               matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        PodajprdkoEditField       matlab.ui.control.NumericEditField
        WykonajButton             matlab.ui.control.Button
        PodajprdkoEditFieldLabel  matlab.ui.control.Label
        KtKnob                    matlab.ui.control.Knob
        KtKnobLabel               matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Data_x % argumenty
        Data_y % wartości funkcji
        x_max % x maksymalna
        x_kosz %pozycja kosza
        x_opt %odlegosc przy danym kącie i prędkości
        prop %proporcjonalność wielkości kwadratu do prędkości
        punkty %pukkty zdoyte
        %x_pozycja = 100 %pozycja obrazka
        player %do odtwarzania dwięku
    end
    
    methods (Access = private)

        function od_nowa(app)
            app.Label.Text = "";
            app.ZapisgryTextArea.Visible = "off";

            plot(app.UIAxes,0,0,"o")
            gen_dane(app)

            app.prop = app.x_max/app.PodajprdkoEditField.Value;
            app.x_kosz = rand()*app.x_max;

            gen_kosz(app)
            
        end
        
        %generuje dane dla rzutu ukosnego
        function gen_dane(app)

            x_pocz = 0;
            y_pocz = 0;

            v = app.PodajprdkoEditField.Value;
            g = 9.81;
            kat = app.KtKnob.Value*pi/180;

            y_max = (v*sin(pi/2))^2/(2*g);
            app.x_max = v^2*sin(pi/2)/g;
            app.UIAxes.XLim = [0,ceil(app.x_max)];
            app.UIAxes.YLim = [0,ceil(y_max)];

            app.x_opt = v^2*sin(2*kat)/g;

            app.Data_x = x_pocz:0.001:app.x_opt;
            app.Data_y = y_pocz+tan(kat).*app.Data_x-0.5*g*app.Data_x.^2/(v*cos(kat))^2;

        end

           %generuje położenie celu na wykresie
        function gen_kosz(app)
            rectangle(app.UIAxes,'Position',[app.x_kosz 0 app.prop app.prop])  
           
        end

        %rysuje potencjalny lot kulki
        function rysuj(app)
            plot(app.UIAxes,app.Data_x,app.Data_y,"--")
            gen_kosz(app)

            
        end
        %animacja rzutu oraz sprawdzanie czy cel został osiągnięty
        function animacja(app)
            for i = 1:app.PodajprdkoEditField.Value*10:app.x_opt*1000+2
                pause(0.01)
                plot(app.UIAxes,app.Data_x(i),app.Data_y(i),"o")

                gen_kosz(app)

                if app.CheckBox.Value == true
                    app.CheckBox.Value = false;
                    od_nowa(app);
                return;
                end

            end
            %sprawdzenie czy ma zagrać muzyczka
            value = app.DwikSwitch.Value;
 
            if app.Data_x(end) >= app.x_kosz && app.Data_x(end) <= app.x_kosz+app.prop
                  if strcmp(value, 'On')
                        graj(app)
                  end

                    app.Label.Text = "Gratulacje!! Udało ci się trafić do celu";
                    app.punkty = app.punkty + 1;
            else
                if strcmp(value, 'On')                  
                    porazka(app)
                end

                    app.Label.Text = "Niestety próba się nie powiodła :(";

            end
            %aktualizacja punktów
            app.Label_2.Text = "Punkty: " + app.punkty;

            pause(2)
            od_nowa(app)
            
        end
        
        function graj(app)
        [y,Fs] = audioread("bonus_point.mp3",'native');
        sound(y,Fs)
        end

         function porazka(app)
             [y,Fs] = audioread("blad.mp3",'native');
            sound(y,Fs)
         end
                
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            od_nowa(app)   
            app.Label_2.Text = "Punkty: 0";
            app.punkty = 0;
        end

        % Value changed function: KtKnob
        function KtKnobValueChanged(app, event)
            gen_dane(app)
            rysuj(app)

        end

        % Value changing function: KtKnob
        function KtKnobValueChanging(app, event)
            gen_dane(app)
            rysuj(app)
        end

        % Value changed function: PodajprdkoEditField
        function PodajprdkoEditFieldValueChanged(app, event)
            od_nowa(app)
            rysuj(app)
        end

        % Button pushed function: WykonajButton
        function WykonajButtonPushed(app, event)
             animacja(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.CheckBox.Value = true;
            od_nowa(app)
        end

        % Button pushed function: ZapisgryButton
        function ZapisgryButtonPushed(app, event)
            app.ZapisgryTextArea.Visible = "on";
            if isfile("C:\\Users\\Dell\\Desktop\\rzut ukośny\\wyniki.txt")
                 file = fopen('C:\\Users\\Dell\\Desktop\\rzut ukośny\\wyniki.txt', 'r');
                dane = fread(file, '*char')'; % Odczytaj dane jako 'char' array
                fclose(file); 
                app.ZapisgryTextArea.Value = string(dane); % Konwersja na typ 'string'
            else
                app.ZapisgryTextArea.Value = "Plik nie zawiera żadnego zapisu z poprzednich gier :(";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
   
            file=fopen("C:\\Users\\Dell\\Desktop\\rzut ukośny\\wyniki.txt","a+");
            ptk = app.punkty;
            fprintf(file,"%s ptk: %s\n",string(datetime),num2str(ptk));
            fclose(file);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.KtKnobLabel = uilabel(app.UIFigure);
            app.KtKnobLabel.Text = 'Kąt';
            app.KtKnob = uiknob(app.UIFigure, 'continuous');
            app.KtKnob.Limits = [0 90];
            app.KtKnob.ValueChangedFcn = createCallbackFcn(app, @KtKnobValueChanged, true);
            app.KtKnob.ValueChangingFcn = createCallbackFcn(app, @KtKnobValueChanging, true);
            app.KtKnob.Value = 45;
            app.PodajprdkoEditFieldLabel = uilabel(app.UIFigure);
            app.PodajprdkoEditFieldLabel.Text = 'Podaj prędkość';
            app.WykonajButton = uibutton(app.UIFigure, 'push');
            app.WykonajButton.ButtonPushedFcn = createCallbackFcn(app, @WykonajButtonPushed, true);
            app.WykonajButton.Text = 'Wykonaj';
            app.PodajprdkoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajprdkoEditField.Limits = [1 Inf];
            app.PodajprdkoEditField.ValueChangedFcn = createCallbackFcn(app, @PodajprdkoEditFieldValueChanged, true);
            app.PodajprdkoEditField.Value = 10;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.DwikSwitchLabel = uilabel(app.UIFigure);
            app.DwikSwitchLabel.Text = 'Dźwięk';
            app.DwikSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZapisgryButton = uibutton(app.UIFigure, 'push');
            app.ZapisgryButton.ButtonPushedFcn = createCallbackFcn(app, @ZapisgryButtonPushed, true);
            app.ZapisgryButton.Text = 'Zapis gry';
            app.ZapisgryTextArea = uitextarea(app.UIFigure);
            app.ZapisgryTextArea.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rzut_ukosny

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end